classdef QmodelGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        business             matlab.ui.control.Table
        Economy              matlab.ui.control.Table
        businessLabel        matlab.ui.control.Label
        EconomyLabel         matlab.ui.control.Label
        generate             matlab.ui.control.StateButton
        BQ                   matlab.ui.control.Table
        EcoQ                 matlab.ui.control.Table
        luggBQ               matlab.ui.control.Table
        luggEcoQ             matlab.ui.control.Table
        IncrementTimeButton  matlab.ui.control.StateButton
        Time                 matlab.ui.control.Table
        ContextMenu          matlab.ui.container.ContextMenu
        Menu                 matlab.ui.container.Menu
        Menu2                matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Property % Description
       
         
            lambda = 10; %per minute 
            mu = 5; %per minute 
            passengers=100;
            
            %Airlines and Airplanes available, random generation if customer airline
            %and airport
            airlines = ["Egypt_Air","Saudi_Air"];
            c_line= [];
            airplanes = ["Plane1","Plane2"];
            c_plane = [];
            
            %Creating luggage assignmnet
            luggage = 0;  
            
            %Creating two distributions for the classes 
            %for business class 
            b_passengers=0; %number of business 
            b_inter_arrival = [];
            b_customer_arrival = [];
            b_cus_service_time = [];
            
            %for economy class 
            E_passengers=0; %number of economy 
            E_inter_arrival = [];
         
            E_customer_arrival = [];
  
            E_cus_service_time = [];
            
            %Creating Empty business and economy queues
            b_queue = [];
            E_queue = [];
            %specific Q for extra luggage 
            b_lugg_queue = [];
            E_lugg_queue = [];
            
            %Business and Economy customer counters for simulation
            b_passenger = 0;
            E_passenger = 0;
            
            %Prompting users to enter servers for both Airlines
            
            
            idle_servers1=1; %intialize all servers as idle at first 
            lugg_server1=0; %intializing a special server for luggage 


            idle_servers2=1; %intialize all servers as idle at first 
            lugg_server2=0; %intializing a special server for luggage 0 for idle and 1 for busy 
            
            
            %timer variable to track time during simulation
            time = 0;  
            
            sim_time=0;
            
            
            %variables for calculating time stamp at which service ends for a customer
            time_service_ends=0;
            lugg_time_sevice_ends=0;
            %arrays for holding the time stamps of the service ends 
            service_ends_servers1=[];
            service_ends_servers2=[];
            service_ends_lugg1=[];
            service_ends_lugg2=[];
            
            
            index=0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: generate
        function generateValueChanged(app, event)
  
           app.c_line=randsample(app.airlines,app.passengers,true);
           app.c_plane = randsample(app.airplanes,app.passengers,true);
                        
           app.luggage = randi(40 ,1,app.passengers); 


            %Creating two distributions for the classes 
            %for business class 
            b_pass=1:1:0.2*app.passengers;
            app.b_passengers=0.2*app.passengers; %number of business 
            app.b_inter_arrival = poissrnd(app.lambda,1,app.b_passengers);
            app.b_inter_arrival=app.b_inter_arrival+1;
            app.b_customer_arrival = cumsum(app.b_inter_arrival);
            app.b_customer_arrival(1)=1;
            app.b_cus_service_time = exprnd(app.mu,1,app.b_passengers);
            T_bus = table(b_pass', app.b_customer_arrival', app.b_cus_service_time', app.luggage(1:0.2*app.passengers)' ,app.c_line(1:0.2*app.passengers)',app.c_plane(1:0.2*app.passengers)', 'VariableNames', {'passenger', 'customer_arrival', 'cus_service_time', 'luggage', 'airline', 'plane'});
            app.business.Data=T_bus;
            
            %for economy class 
            E_pass=1:1:0.8*app.passengers;
            app.E_passengers=0.8*app.passengers; %number of economy 
            app.E_inter_arrival = poissrnd(app.lambda,1,app.E_passengers);
            app.E_inter_arrival=app.E_inter_arrival+1; 
            app.E_customer_arrival = cumsum(app.E_inter_arrival);
            app.E_customer_arrival(1)=1;
            app.E_cus_service_time = exprnd(app.mu,1,app.E_passengers);
            T_Eco = table(E_pass', app.E_customer_arrival', app.E_cus_service_time', app.luggage(1:0.8*app.passengers)' ,app.c_line(1:0.8*app.passengers)',app.c_plane(1:0.8*app.passengers)', 'VariableNames', {'passenger', 'customer_arrival', 'cus_service_time', 'luggage', 'airline', 'plane'});
            app.Economy.Data=T_Eco;
            
               %Calculating simulation time according to last customer in queue
            if ((app.b_customer_arrival(end)+app.b_cus_service_time(end)) > (app.E_customer_arrival(end)+app.E_cus_service_time(end)))
                app.sim_time = app.b_customer_arrival(end)+app.b_cus_service_time(end);
            else 
                app.sim_time = app.E_customer_arrival(end)+app.E_cus_service_time(end);
            end
            
            app.sim_time = round(app.sim_time);
            
        end

        % Value changed function: IncrementTimeButton
        function IncrementTimeButtonValueChanged(app, event)
 
         
            %Simulationnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnnn
                app.time = app.time+1;


                %when time is equal arrival time of one of the customers 
                    %Business Customer Enters
                   if(ismember(app.time,app.b_customer_arrival))
                       app.b_passenger = app.b_passenger +1;
                       %if luggage does not exceed the Max., the passenger enters
                       %business Q
                       if(app.luggage(app.b_passenger)<=32)
                            app.b_queue = [app.b_queue app.b_passenger];
                       %if luggage does exceed the Max., the passenger enters
                       %business luggage Q
                       else 
                           app.b_lugg_queue = [app.b_lugg_queue app.b_passenger]; 
                       end 
                       app.index = app.b_passenger;
                   end 
                   
                    %Economy Customer Enters
                   if(ismember(app.time,app.E_customer_arrival))
                       app.E_passenger = app.E_passenger +1;
                       %if luggage does not exceed the Max., the passenger enters
                       %economy Q
                       if(app.luggage(app.E_passenger)<=23)
                           app.E_queue = [app.E_queue app.E_passenger]; 
                        %if luggage does exceed the Max., the passenger enters
                       %economy luggage Q
                       else
                           app.E_lugg_queue = [app.E_lugg_queue app.E_passenger]; 
                       end 
                       app.index = app.E_passenger;
                   end
                   
                   
                T_EcoQ = table(app.E_queue', 'VariableNames', {'Q'});
                app.EcoQ.Data=T_EcoQ;
                
                T_BQ = table(app.b_queue', 'VariableNames', {'Q'});
                app.BQ.Data=T_BQ;
                
                T_luggBQ = table(app.b_lugg_queue', 'VariableNames', {'Q'});
                app.luggBQ.Data=T_luggBQ;
                
                T_luggEcoQ = table(app.E_lugg_queue', 'VariableNames', {'Q'});
                app.luggEcoQ.Data=T_luggEcoQ;
                
                T_time = table(app.time', 'VariableNames', {'time'});
                app.Time.Data=T_time; 
             
                
             %$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$   
                %Services customer of Burger_Air Airline for either plane(common terminal)
                if(app.c_line(app.index)=="Egypt_Air" && (app.c_plane(app.index) == "Plane2" || app.c_plane(app.index) == "Plane1")) 
                 %Available Burger Airline server and Both Qs are not empty (Prioritizing Business)
                 if(app.idle_servers1>0 &&  ~isempty(app.b_queue)&&  ~isempty(app.E_queue))
                     % calcute the service end time 
                     app.time_service_ends = app.time + app.b_cus_service_time(app.b_passenger);
                     app.time_service_ends = round(app.time_service_ends);
                    app.service_ends_servers1=[app.service_ends_servers1 app.time_service_ends];
                     app.b_queue = app.b_queue(:,2:end); 
                     app.idle_servers1=app.idle_servers1-1;
                   %Available Burger Airline server, Empty Business Q (Chooses Economy)
                elseif(app.idle_servers1>0 &&  isempty(app.b_queue)&&  ~isempty(app.E_queue))
                    % calcute the service end time 
                     app.time_service_ends = app.time + app.E_cus_service_time(app.E_passenger);
                     app.time_service_ends = round(app.time_service_ends);
                     app.service_ends_servers1=[app.service_ends_servers1 app.time_service_ends];
                     app.E_queue = app.E_queue(:,2:end); 
                     app.idle_servers1=app.idle_servers1-1;
                 %Available Burger Airline server, Empty Economy Q (Chooses Business)
                 elseif(app.idle_servers1>0 &&  ~isempty(app.b_queue)&&  isempty(app.E_queue))
                     % calcute the service end time 
                     app.time_service_ends = app.time + app.b_cus_service_time(app.b_passenger);
                     app.time_service_ends = round(app.time_service_ends);
                     app.service_ends_servers1=[app.service_ends_servers1 app.time_service_ends];
                     app.b_queue = app.b_queue(:,2:end); 
                     app.idle_servers1=app.idle_servers1-1;
                 end
                
                 
                 %--------------------------------------
                 %luggage handling 
                 %there are passengers in both business and economy luggae Qs and the special counter is empty(Prioritizing Business)
                 if(app.lugg_server1==0&& ~isempty(app.b_lugg_queue) && (~isempty(app.E_lugg_queue)||isempty(app.E_lugg_queue)))
                     % calcute the service end time 
                    app.lugg_time_sevice_ends = app.time + app.b_cus_service_time(app.b_passenger);
                     app.lugg_time_sevice_ends = round(app.lugg_time_sevice_ends);
                     app.service_ends_lugg1 = [app.service_ends_lugg1 app.lugg_time_sevice_ends];
                     app.b_lugg_queue = app.b_lugg_queue(:,2:end);
                     app.lugg_server1=1;
                 %there are passengers in economy luggae Q, business luggae Q is empty, and the special counter is empty(Chooses Economy)
                 elseif(app.lugg_server1==0&& isempty(app.b_lugg_queue) && ~isempty(app.E_lugg_queue))
                     % calcute the service end time 
                     app.lugg_time_sevice_ends = app.time + app.E_cus_service_time(app.E_passenger);
                     app.lugg_time_sevice_ends = round(app.lugg_time_sevice_ends);
                     app.service_ends_lugg1 = [app.service_ends_lugg1 app.lugg_time_sevice_ends];
                     app.E_lugg_queue = app.E_lugg_queue(:,2:end);
                     app.lugg_server1=1;
                 end 
                
                 
                end
                
                 %Releasing servers when service ends for current passenger
                 if(~isempty(app.service_ends_servers1) &&app.time >= app.service_ends_servers1(1) ) %if time is equal to the time the service ends
                    app.idle_servers1=app.idle_servers1+1;            %make sever idle
                    app.service_ends_servers1 = app.service_ends_servers1(:,2:end);
                 end
                 if(~isempty(app.service_ends_lugg1) &&app.time >= app.service_ends_lugg1(1)) %if time is equal to the time the service ends
                    app.lugg_server1=0;            %make sever idle
                    app.service_ends_lugg1 = app.service_ends_lugg1(:,2:end);
                 end 
                 
            %$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$
                %Services customer of Pizza_Air Airline for either plane(common terminal)
                if(app.c_line(app.index)=="Saudi_Air" && (app.c_plane(app.index) == "Plane2" || app.c_plane(app.index) == "Plane1"))
                    %Available Burger Airline server and Both Qs are not empty (Prioritizing Business)
                    if(app.idle_servers2>0 &&  ~isempty(app.b_queue)&&  ~isempty(app.E_queue)) 
                     app.time_service_ends = app.time + app.b_cus_service_time(app.b_passenger);
                     app.time_service_ends = round(app.time_service_ends);
                     app.service_ends_servers2=[app.service_ends_servers2 app.time_service_ends];
                     app.b_queue = app.b_queue(:,2:end); 
                     app.idle_servers2=app.idle_servers2-1; 
                     
                    %Available Burger Airline server, Empty Business Q (Chooses Economy)
                    elseif(app.idle_servers2>0 &&  isempty(app.b_queue)&&  ~isempty(app.E_queue))
                     app.time_service_ends = app.time + app.E_cus_service_time(app.E_passenger);
                     app.time_service_ends = round(app.time_service_ends);
                     app.service_ends_servers2=[app.service_ends_servers2 app.time_service_ends];
                     app.E_queue = app.E_queue(:,2:end); 
                     app.idle_servers2=app.idle_servers2-1;
                     
                    %Available Burger Airline server, Empty Economy Q (Chooses Business)
                    elseif(app.idle_servers2>0 &&  ~isempty(app.b_queue)&&  isempty(app.E_queue))
                     app.time_service_ends = app.time + app.b_cus_service_time(app.b_passenger);
                     app.time_service_ends = round(app.time_service_ends);
                     app.service_ends_servers2=[app.service_ends_servers2 app.time_service_ends];
                     app.b_queue = app.b_queue(:,2:end); 
                     app.idle_servers2=app.idle_servers2-1;
                    end
                    
                    
                 %---------------------------------------------------------------------------------------
                 %luggage handling 
                 %there are passengers in both business and economy luggae Qs and the special counter is empty(Prioritizing Business)
                 if(app.lugg_server2==0&& ~isempty(app.b_lugg_queue) && (~isempty(app.E_lugg_queue)||isempty(app.E_lugg_queue)))
                     app.lugg_server2=1;
                     app.lugg_time_sevice_ends = app.time + app.b_cus_service_time(app.b_passenger);
                     app.lugg_time_sevice_ends = round(app.lugg_time_sevice_ends);
                     app.service_ends_lugg2 = [app.service_ends_lugg2 app.lugg_time_sevice_ends];
                     app.b_lugg_queue = app.b_lugg_queue(:,2:end);
                 %there are passengers in economy luggae Q, business luggae Q is empty, and the special counter is empty(Chooses Economy)
                 elseif(app.lugg_server2==0&& isempty(app.b_lugg_queue) && ~isempty(app.E_lugg_queue))
                     app.lugg_server2=1;
                     app.lugg_time_sevice_ends = app.time + app.E_cus_service_time(app.E_passenger);
                     app.lugg_time_sevice_ends = round(app.lugg_time_sevice_ends);
                     app.service_ends_lugg2 = [app.service_ends_lugg2 app.lugg_time_sevice_ends];
                     app.E_lugg_queue = app.E_lugg_queue(:,2:end);
                 end 
                end
                
                %Releasing servers when service ends for current customer
                 if(~isempty(app.service_ends_servers2) &&app.time >= app.service_ends_servers2(1)) %if time is equal to the time the service ends
                     app.idle_servers2=app.idle_servers2+1;            %make sever idle
                     app.service_ends_servers2=app.service_ends_servers2(:,2:end);
                 end
                 if( ~isempty(app.service_ends_lugg2) && app.time >= app.service_ends_lugg2(1)) %if time is equal to the time the service ends
                    app.lugg_server2=0;            %make sever idle
                    app.service_ends_lugg2=app.service_ends_lugg2(:,2:end);
                 end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.business = uitable(app.UIFigure);
            app.business.ColumnName = {'Passenger'; 'arrival time'; 'service time'; 'luggage'; 'airline'; 'plane'};
            app.business.RowName = {};
            app.Economy = uitable(app.UIFigure);
            app.Economy.ColumnName = {'Passenger'; 'arrival time'; 'service time'; 'luggage'; 'airline'; 'plane'};
            app.Economy.RowName = {};
            app.businessLabel = uilabel(app.UIFigure);
            app.businessLabel.Text = 'business';
            app.EconomyLabel = uilabel(app.UIFigure);
            app.EconomyLabel.Text = 'Economy';
            app.generate = uibutton(app.UIFigure, 'state');
            app.generate.ValueChangedFcn = createCallbackFcn(app, @generateValueChanged, true);
            app.generate.Text = 'generate';
            app.BQ = uitable(app.UIFigure);
            app.BQ.ColumnName = {'business Q'};
            app.BQ.RowName = {};
            app.EcoQ = uitable(app.UIFigure);
            app.EcoQ.ColumnName = {'Eco Q'};
            app.EcoQ.RowName = {};
            app.luggBQ = uitable(app.UIFigure);
            app.luggBQ.ColumnName = {'Luggage business Q'};
            app.luggBQ.RowName = {};
            app.luggEcoQ = uitable(app.UIFigure);
            app.luggEcoQ.ColumnName = {'Luggage Eco Q'};
            app.luggEcoQ.RowName = {};
            app.IncrementTimeButton = uibutton(app.UIFigure, 'state');
            app.IncrementTimeButton.ValueChangedFcn = createCallbackFcn(app, @IncrementTimeButtonValueChanged, true);
            app.IncrementTimeButton.Text = 'Increment Time ';
            app.Time = uitable(app.UIFigure);
            app.Time.ColumnName = {'Time:'};
            app.Time.RowName = {};
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QmodelGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end